classdef Altivar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label_10           matlab.ui.control.Label
        Label_9            matlab.ui.control.Label
        Label_8            matlab.ui.control.Label
        barLabel_3         matlab.ui.control.Label
        barLabel_2         matlab.ui.control.Label
        barLabel           matlab.ui.control.Label
        tempInd_3          matlab.ui.control.NumericEditField
        tempInd_2          matlab.ui.control.NumericEditField
        tempInd_1          matlab.ui.control.NumericEditField
        ESTADOLabel_3      matlab.ui.control.Label
        ESTADOLabel_2      matlab.ui.control.Label
        ESTADOLabel        matlab.ui.control.Label
        SALIDALabel_3      matlab.ui.control.Label
        SALIDALabel_2      matlab.ui.control.Label
        SALIDALabel        matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        Button_4           matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        ONRESETButton      matlab.ui.control.Button
        AmbienteLabel      matlab.ui.control.Label
        TempAmbiente       matlab.ui.control.NumericEditField
        RepararButton_3    matlab.ui.control.Button
        RepararButton_2    matlab.ui.control.Button
        RepararButton_1    matlab.ui.control.Button
        tempIndividual_3   matlab.ui.control.LinearGauge
        PresionC_3         matlab.ui.control.Gauge
        tempIndividual_2   matlab.ui.control.LinearGauge
        PresionC_2         matlab.ui.control.Gauge
        tempIndividual_1   matlab.ui.control.LinearGauge
        PresionC_1         matlab.ui.control.Gauge
        SetPointLabel      matlab.ui.control.Label
        C3_ESTADO          matlab.ui.control.TextArea
        C3_SALIDA          matlab.ui.control.TextArea
        C3Lamp             matlab.ui.control.Lamp
        C2_ESTADO          matlab.ui.control.TextArea
        C2_SALIDA          matlab.ui.control.TextArea
        C2Lamp             matlab.ui.control.Lamp
        TemperaturaSalida  matlab.ui.control.EditField
        Diferencia         matlab.ui.control.EditField
        SetPoint           matlab.ui.control.NumericEditField
        C1_ESTADO          matlab.ui.control.TextArea
        C1_SALIDA          matlab.ui.control.TextArea
        C1Lamp             matlab.ui.control.Lamp
        Button             matlab.ui.control.Button
        Button_5           matlab.ui.control.Button
        Button_6           matlab.ui.control.Button
        Button_7           matlab.ui.control.Button
        TempSalida         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%           //////////////////////////////////////////////////////////////////////////////////
%           /*
%             
%             Creado por @nobrayan
%             
%           */
%           //////////////////////////////////////////////////////////////////////////////////
            
            clc;
            app.TempSalida.XLim = [1 10];
            app.TempSalida.YLim = [1 35];
            app.SetPoint.Value = 22;
            
            app.TempAmbiente.Value = 25;
            app.TemperaturaSalida.Value = "25";
            app.Diferencia.Value = "0";
            
            app.C1_SALIDA.Value = "0";
            app.C2_SALIDA.Value = "0";
            app.C3_SALIDA.Value = "0";
            
            app.C1_ESTADO.Value = "Apagado";
            app.C2_ESTADO.Value = "Apagado";
            app.C3_ESTADO.Value = "Apagado";
            
            app.C1Lamp.Color = [1 0 0];
            app.C2Lamp.Color = [1 0 0];
            app.C3Lamp.Color = [1 0 0];
            
            
        end

        % Button pushed function: RepararButton_1
        function RepararButton_1Pushed(app, event)
            if (app.C1_ESTADO.Value == "Daño") && (app.tempIndividual_1.Value <= app.TempAmbiente.Value)
                app.C1_ESTADO.Value = "Reparado";
            end
        end

        % Button pushed function: RepararButton_2
        function RepararButton_2Pushed(app, event)
            if app.C2_ESTADO.Value == "Daño" && (app.tempIndividual_2.Value <= app.TempAmbiente.Value)
                app.C2_ESTADO.Value = "Reparado";
            end
        end

        % Button pushed function: RepararButton_3
        function RepararButton_3Pushed(app, event)
            if app.C3_ESTADO.Value == "Daño" && (app.tempIndividual_3.Value <= app.TempAmbiente.Value)
                app.C3_ESTADO.Value = "Reparado";
            end
        end

        % Button pushed function: ONRESETButton
        function ONRESETButtonPushed(app, event)
            Start = 1;
            
            app.C1Lamp.Color = [0 1 0];
            app.C2Lamp.Color = [0 1 0];
            app.C3Lamp.Color = [0 1 0];
            
            app.C1_ESTADO.Value = "Encendido";
            app.C2_ESTADO.Value = "Encendido";
            app.C3_ESTADO.Value = "Encendido";
            
            %TEMPERATURAS
            global tempAmbiente
            tempAmbiente = 25;
            tempSalida = tempAmbiente;
            anteriorTempSalida = 0;
            tempIndividual = [tempAmbiente tempAmbiente tempAmbiente];
            
            Incremento = [tempAmbiente tempAmbiente tempAmbiente tempAmbiente tempAmbiente tempAmbiente tempAmbiente tempAmbiente tempAmbiente tempAmbiente];
            presionIndividual = [0 0 0];

            %VALOR SALIDA
            Salida = 0;
            salidaIndividual = [0 0 0];

            %NUMERO DE EQUIPOS ENCENDIDOS
            eqEncendidos = 0;
            global eqEncendidoSuma_1
            global eqEncendidoSuma_2
            global eqEncendidoSuma_3
            eqEncendidoSuma_1 = 1;
            eqEncendidoSuma_2 = 1;
            eqEncendidoSuma_3 = 1;

            %VALORES PID
            Kp = 2;    %Constante proporcional
            Ki = 2;    %Constante integral
            Kd = 2;    %Constante derivativa
            
            %Calculo Variables
            integral = 0;
            derivada = 0;
            
            %Errores
            Error = 0;
            ultimoError = 0;
            
            %Calculo Tiempo de Muestreo
            tiempoMuestreo = 0;

            while Start == 1
                tic;
                
                tempAmbiente = app.TempAmbiente.Value;
                eqEncendidos = eqEncendidoSuma_1 + eqEncendidoSuma_2 + eqEncendidoSuma_3;
                
                Kp = (6-eqEncendidos)*0.4;
                Ki = (6-eqEncendidos)*0.4;
                Kd = (6-eqEncendidos)*0.4;

                if (tempSalida > (tempAmbiente/3)*2) && (tempSalida < tempAmbiente) 
                    tempSalida = tempSalida + 0.125;
                elseif (tempSalida > tempAmbiente/3) && (tempSalida < (tempAmbiente/3)*2)
                    tempSalida = tempSalida + 0.250;
                elseif (tempSalida > 0) && (tempSalida < tempAmbiente/3)
                    tempSalida = tempSalida + 0.5;
                end
                
                TempSens = tempSalida;

                %Valor deseado en Celsius
                valorSetPoint = app.SetPoint.Value;
                Error = TempSens - valorSetPoint; %Invertido

                integral = Error*tiempoMuestreo;
                derivada = (Error-ultimoError)/tiempoMuestreo;
                 
                Salida = Kp*Error + Ki*integral + Kd*derivada;
                 
                %Saturador
                if Salida < 0
                    Salida = 0;
                elseif Salida > 1023
                    Salida = 1023;
                end
                porSalida = (Salida*100)/1023;
                tempSalida = tempSalida - ((eqEncendidos*2/3)*porSalida)/100;

                if eqEncendidoSuma_1 == 1
                    salidaIndividual(1) = porSalida;
                    presionIndividual(1) = (porSalida*40)/100;
                    app.C1_ESTADO.Value = "Encendido";
                    if tempIndividual(1) < 130
                        tempIndividual(1) = tempIndividual(1) + (4*salidaIndividual(1))/100;
                    end
                else
                    salidaIndividual(1) = 0;
                    presionIndividual(1) = 0;
                    if app.C1Lamp.Color ~= [0.5 0.5 0.5]
                        app.C1_ESTADO.Value = "Apagado";
                    end
                    if tempIndividual(1) > tempAmbiente
                        tempIndividual(1) = tempIndividual(1) - 2;
                    end
                end

                if eqEncendidoSuma_2 == 1
                    salidaIndividual(2) = porSalida;
                    presionIndividual(2) = (porSalida*40)/100;
                    app.C2_ESTADO.Value = "Encendido";
                    if tempIndividual(2) < 130
                        tempIndividual(2) = tempIndividual(2) + (4*salidaIndividual(2))/100;
                    end
                else
                    salidaIndividual(2) = 0;
                    presionIndividual(2) = 0;
                    if app.C2Lamp.Color ~= [0.5 0.5 0.5]
                        app.C2_ESTADO.Value = "Apagado";
                    end
                    if tempIndividual(2) > tempAmbiente
                        tempIndividual(2) = tempIndividual(2) - 2;
                    end
                end

                if eqEncendidoSuma_3 == 1
                    salidaIndividual(3) = porSalida;
                    presionIndividual(3) = (porSalida*40)/100;
                    app.C3_ESTADO.Value = "Encendido";
                    if tempIndividual(3) < 130
                        tempIndividual(3) = tempIndividual(3) + (4*salidaIndividual(3))/100;
                    end
                else
                    salidaIndividual(3) = 0;
                    presionIndividual(3) = 0;
                    if app.C3Lamp.Color ~= [0.5 0.5 0.5]
                        app.C3_ESTADO.Value = "Apagado";
                    end
                    if tempIndividual(3) > tempAmbiente
                        tempIndividual(3) = tempIndividual(3) - 2;
                    end
                end

                if (tempIndividual(1) > 90) && (tempIndividual(1) <= 130) && (eqEncendidoSuma_1 == 1)
                    app.C1Lamp.Color = [1 1 0];
                    app.C1_ESTADO.Value = "Alta Temperatura";
                elseif tempIndividual(1) > 130
                    app.C1Lamp.Color = [0.5 0.5 0.5];
                    eqEncendidoSuma_1 = 0;
                    app.C1_ESTADO.Value = "Daño";
                end

                if (tempIndividual(2) > 90) && (tempIndividual(2) <= 130) && (eqEncendidoSuma_2 == 1)
                    app.C2Lamp.Color = [1 1 0];
                    app.C2_ESTADO.Value = "Alta Temperatura";
                elseif tempIndividual(2) > 130
                    app.C2Lamp.Color = [0.5 0.5 0.5];
                    eqEncendidoSuma_2 = 0;
                    app.C2_ESTADO.Value = "Daño";
                end

                if (tempIndividual(3) > 90) && (tempIndividual(3) <= 130) && (eqEncendidoSuma_3 == 1)
                    app.C3Lamp.Color = [1 1 0];
                    app.C3_ESTADO.Value = "Alta Temperatura";
                elseif tempIndividual(3) > 130
                    app.C3Lamp.Color = [0.5 0.5 0.5];
                    eqEncendidoSuma_3 = 0;
                    app.C3_ESTADO.Value = "Daño";
                end

                %GRAFICACION PRINCIPAL
                for i = 1:length(Incremento)-1
                    Incremento(i) = Incremento(i+1);
                end
                Incremento(length(Incremento)) = tempSalida;
                plot(app.TempSalida,Incremento,'r');
                
                %MOSTRAR INFO
                app.C1_SALIDA.Value = num2str(salidaIndividual(1), '%.2f');
                app.C2_SALIDA.Value = num2str(salidaIndividual(2), '%.2f');
                app.C3_SALIDA.Value = num2str(salidaIndividual(3), '%.2f');

                app.PresionC_1.Value = presionIndividual(1);
                app.PresionC_2.Value = presionIndividual(2);
                app.PresionC_3.Value = presionIndividual(3);

                app.tempIndividual_1.Value = tempIndividual(1);
                app.tempIndividual_2.Value = tempIndividual(2);
                app.tempIndividual_3.Value = tempIndividual(3);
                
                app.tempInd_1.Value = tempIndividual(1);
                app.tempInd_2.Value = tempIndividual(2);
                app.tempInd_3.Value = tempIndividual(3);

                app.Diferencia.Value = num2str(tempSalida-anteriorTempSalida, '%.2f');
                app.TemperaturaSalida.Value = num2str(tempSalida, '%.2f');
                
                anteriorTempSalida = tempSalida;
                ultimoError = Error;
                pause(0.5);
                tiempoMuestreo = toc*1000;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global eqEncendidoSuma_1 tempAmbiente
            if app.C1_ESTADO.Value ~= "Daño"
                if eqEncendidoSuma_1 == 1
                    eqEncendidoSuma_1 = 0;
                    app.C1Lamp.Color = [1 0 0];
                else
                    eqEncendidoSuma_1 = 1;
                    app.C1Lamp.Color = [0 1 0];
                end
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global eqEncendidoSuma_2 tempAmbiente
            if app.C2_ESTADO.Value ~= "Daño"
                if eqEncendidoSuma_2 == 1
                    eqEncendidoSuma_2 = 0;
                    app.C2Lamp.Color = [1 0 0];
                else
                    eqEncendidoSuma_2 = 1;
                    app.C2Lamp.Color = [0 1 0];
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global eqEncendidoSuma_3 tempAmbiente
            if app.C3_ESTADO.Value ~= "Daño"
                if eqEncendidoSuma_3 == 1
                    eqEncendidoSuma_3 = 0;
                    app.C3Lamp.Color = [1 0 0];
                else
                    eqEncendidoSuma_3 = 1;
                    app.C3Lamp.Color = [0 1 0];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TempSalida = uiaxes(app.UIFigure);
            title(app.TempSalida, 'Temperatura de Liquido')
            xlabel(app.TempSalida, 'Tiempo (sg)')
            ylabel(app.TempSalida, 'Celsius (℃)')
            zlabel(app.TempSalida, 'Z')
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Enable = 'off';
            app.Button_7.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.C1Lamp = uilamp(app.UIFigure);
            app.C1_SALIDA = uitextarea(app.UIFigure);
            app.C1_ESTADO = uitextarea(app.UIFigure);
            app.SetPoint = uieditfield(app.UIFigure, 'numeric');
            app.Diferencia = uieditfield(app.UIFigure, 'text');
            app.TemperaturaSalida = uieditfield(app.UIFigure, 'text');
            app.C2Lamp = uilamp(app.UIFigure);
            app.C2_SALIDA = uitextarea(app.UIFigure);
            app.C2_ESTADO = uitextarea(app.UIFigure);
            app.C3Lamp = uilamp(app.UIFigure);
            app.C3_SALIDA = uitextarea(app.UIFigure);
            app.C3_ESTADO = uitextarea(app.UIFigure);
            app.SetPointLabel = uilabel(app.UIFigure);
            app.SetPointLabel.Text = 'SetPoint';
            app.PresionC_1 = uigauge(app.UIFigure, 'circular');
            app.PresionC_1.Limits = [0 40];
            app.PresionC_1.MajorTicks = [0 10 20 30 40];
            app.tempIndividual_1 = uigauge(app.UIFigure, 'linear');
            app.tempIndividual_1.Limits = [0 150];
            app.PresionC_2 = uigauge(app.UIFigure, 'circular');
            app.PresionC_2.Limits = [0 40];
            app.PresionC_2.MajorTicks = [0 10 20 30 40];
            app.tempIndividual_2 = uigauge(app.UIFigure, 'linear');
            app.tempIndividual_2.Limits = [0 150];
            app.PresionC_3 = uigauge(app.UIFigure, 'circular');
            app.PresionC_3.Limits = [0 40];
            app.PresionC_3.MajorTicks = [0 10 20 30 40];
            app.tempIndividual_3 = uigauge(app.UIFigure, 'linear');
            app.tempIndividual_3.Limits = [0 150];
            app.RepararButton_1 = uibutton(app.UIFigure, 'push');
            app.RepararButton_1.ButtonPushedFcn = createCallbackFcn(app, @RepararButton_1Pushed, true);
            app.RepararButton_1.Text = 'Reparar';
            app.RepararButton_2 = uibutton(app.UIFigure, 'push');
            app.RepararButton_2.ButtonPushedFcn = createCallbackFcn(app, @RepararButton_2Pushed, true);
            app.RepararButton_2.Text = 'Reparar';
            app.RepararButton_3 = uibutton(app.UIFigure, 'push');
            app.RepararButton_3.ButtonPushedFcn = createCallbackFcn(app, @RepararButton_3Pushed, true);
            app.RepararButton_3.Text = 'Reparar';
            app.TempAmbiente = uieditfield(app.UIFigure, 'numeric');
            app.AmbienteLabel = uilabel(app.UIFigure);
            app.AmbienteLabel.Text = 'Ambiente';
            app.ONRESETButton = uibutton(app.UIFigure, 'push');
            app.ONRESETButton.ButtonPushedFcn = createCallbackFcn(app, @ONRESETButtonPushed, true);
            app.ONRESETButton.Text = 'ON/RESET';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '▶';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '▶';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '▶';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '℃';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '℃';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '℃';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '℃';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '%';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '%';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '%';
            app.SALIDALabel = uilabel(app.UIFigure);
            app.SALIDALabel.Text = 'SALIDA:';
            app.SALIDALabel_2 = uilabel(app.UIFigure);
            app.SALIDALabel_2.Text = 'SALIDA:';
            app.SALIDALabel_3 = uilabel(app.UIFigure);
            app.SALIDALabel_3.Text = 'SALIDA:';
            app.ESTADOLabel = uilabel(app.UIFigure);
            app.ESTADOLabel.Text = 'ESTADO:';
            app.ESTADOLabel_2 = uilabel(app.UIFigure);
            app.ESTADOLabel_2.Text = 'ESTADO:';
            app.ESTADOLabel_3 = uilabel(app.UIFigure);
            app.ESTADOLabel_3.Text = 'ESTADO:';
            app.tempInd_1 = uieditfield(app.UIFigure, 'numeric');
            app.tempInd_1.ValueDisplayFormat = '%.1f';
            app.tempInd_2 = uieditfield(app.UIFigure, 'numeric');
            app.tempInd_2.ValueDisplayFormat = '%.1f';
            app.tempInd_3 = uieditfield(app.UIFigure, 'numeric');
            app.tempInd_3.ValueDisplayFormat = '%.1f';
            app.barLabel = uilabel(app.UIFigure);
            app.barLabel.Text = 'bar';
            app.barLabel_2 = uilabel(app.UIFigure);
            app.barLabel_2.Text = 'bar';
            app.barLabel_3 = uilabel(app.UIFigure);
            app.barLabel_3.Text = 'bar';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '℃';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '℃';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Altivar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end